classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResultsPanel            matlab.ui.container.Panel
        LeftPanel               matlab.ui.container.Panel
        VhiculesButtonGroup     matlab.ui.container.ButtonGroup
        AroglisseurButton       matlab.ui.control.ToggleButton
        SUVButton               matlab.ui.control.ToggleButton
        VoiturelgreButton       matlab.ui.control.ToggleButton
        ParamtresPanel          matlab.ui.container.Panel
        TypedecontactdupneumatiquePanel  matlab.ui.container.Panel
        contact                 matlab.ui.control.ToggleSwitch
        AngleVolantdegLabel_2   matlab.ui.control.Label
        FermerButton            matlab.ui.control.Button
        LancerSimulationButton  matlab.ui.control.Button
        SolsPanel               matlab.ui.container.Panel
        cb_Verglas              matlab.ui.control.CheckBox
        cb_Neige                matlab.ui.control.CheckBox
        cb_Mouille              matlab.ui.control.CheckBox
        cb_Sec                  matlab.ui.control.CheckBox
        Duree                   matlab.ui.control.NumericEditField
        Angle                   matlab.ui.control.NumericEditField
        AngleVolantdegLabel     matlab.ui.control.Label
        ScenarioGroup           matlab.ui.container.ButtonGroup
        rb_Evitement            matlab.ui.control.RadioButton
        rb_Virage               matlab.ui.control.RadioButton
        KnobVitesse             matlab.ui.control.Knob
        VitessekmhLabel         matlab.ui.control.Label
    end

    
    properties (Access=public)
        Params struct
    end
    methods (Access=private)
        function runSimulationLogic(app,nom_v,v_kmh,angle_deg, duree,sols,scenario,type_contact)
            delete(app.ResultsPanel.Children) ;
            page=tiledlayout(app.ResultsPanel,2, 3,'TileSpacing','compact','Padding','compact');
            title(page,nom_v);
            
            dt=0.005;
            Dem=16;%passage de l'angle de volant à l'angle d'Ackermann
            beta=angle_deg/Dem*pi/180;%rad
            v_ms =v_kmh /3.6;
            Tf=duree;
            t_=(0:dt:Tf)';
            nb=length(t_);
            
            if scenario == 1
                U_ = v_ms*ones(nb,1);
                Beta_ = beta*ones(nb,1);
                gl=0.0;
            elseif scenario == 2
                decel=-2;
                U_ =max(0,v_ms+ decel*t_); 
                Beta_ = zeros(nb,1);
                Beta_(t_>=0.5 & t_<1.0) = beta;
                Beta_(t_>=1.6 & t_<2.2) = -beta;
                gl=-0.2 ;
            end
            
            couleurs ={'b','r','g','m'};   
            plots=[] ;
            noms_legende= {};

            for k=1:length(sols)
               nom_sol= sols{k};
               mu_val =get_mu_sol(nom_sol);
               mu_ =mu_val*ones(nb,1);

               Res=simulateur_dynamique(Tf,dt,U_,Beta_,mu_, app.Params,  type_contact,gl );
               couleur=couleurs{k};
               
               line = app.plot_results(page,Res, Beta_,couleur, k);
               plots=[plots, line] ;
               noms_legende{end+1}= nom_sol;
            end
           
        end

        function traj = plot_results(app,layout,Res, Beta, col,i)
            % 1 Trajectoire
            ax1 = nexttile(layout,1);
            if i==1, hold(ax1,'off'); end
            traj = plot(ax1,Res.X_,  Res.Y_,'Color', col, 'LineWidth',2);
            hold(ax1, 'on' ); 
            grid(ax1, 'on' ); axis(ax1,'equal');
            if i==1
                xlabel(ax1, 'X (m)' ); 
                ylabel(ax1, 'Y (m)');
                title(ax1,  'Trajectoire');
            end
            
            %2 Vitesse
            ax2 = nexttile(layout,2) ;
            if i==1, hold(ax2,'off'); end
            plot(ax2, Res.Temps_, Res.Vitesse_,'k--');
            hold(ax2,'on');
            grid(ax2,'on');
            if i==1
                xlabel(ax2,'t (s)');
                ylabel(ax2,'U (km/h)');
                title(ax2, 'Vitesse véhicule'); 
            end
            
            %3  Volant
            ax3 =  nexttile(layout,3) ;
            if i==1, hold(ax3,'off'); end
            plot(ax3, Res.Temps_, Beta*180/pi,'k--') ;
            hold(ax3,'on') ;
            grid(ax3,'on');
            if i==1
                xlabel(ax3,'t (s)');
                ylabel(ax3,'beta (deg)') ;
                title(ax3,'Angle de braquage');
            end
            
            % 4 Accel Lat   
            ax4 = nexttile(layout,4);
            if i==1 hold(ax4,'off'); end
            plot(ax4, Res.Temps_, Res.Ay_,'Color',col,'LineWidth',1.5);
            hold(ax4,'on');
            grid(ax4,'on');
            if i == 1%limite glissement
                C =app.Params.C;
                h=app.Params.h ;
                limite_g =C/(2*h);%en g
                yline(ax4,limite_g,'r--','Risque de retournement','LineWidth',2) ;
                yline(ax4,-limite_g,'r--','LineWidth',2); 
                xlabel(ax4,'t (s)') ;
                ylabel(ax4,'AyCdG (g)');
                title(ax4,'Accélération latérale');
            end
            % 5 Vitesse Lacet
            ax5 = nexttile(layout,5);
            if i==1, hold(ax5,'off'); end
            plot(ax5, Res.Temps_, Res.Dtheta_,'Color', col, 'LineWidth', 1.5);
            hold(ax5, 'on');
            plot(ax5, Res.Temps_, Res.Dtheta_theo_, 'k--');
            grid(ax5,'on');
            if i==1 
                xlabel(ax5,'t (s)');
                ylabel(ax5,'d\theta (deg/s)');
                title(ax5, ['Vitesse angle de lacet']) ; 
                str = {'-  : Réel  ,  ... : Théorique'};
                text(ax5, 0.02, 0.98, str,'Units', 'normalized',  'VerticalAlignment', 'top', 'BackgroundColor', 'white','EdgeColor', 'black');
            end
            
            %6 Angles dérives 
            ax6 = nexttile(layout,6);
            if i==1, hold(ax6,'off'); end
            plot(ax6, Res.Temps_, Res.Deltaf_, 'Color', col);
            hold(ax6,'on') ;
            plot(ax6, Res.Temps_, Res.Deltar_, 'Color', col, 'LineStyle', '--');
            grid(ax6,'on');
            if i==1
                xlabel(ax6,'t (s)') ;
                ylabel(ax6,'deltaf/r (deg)');
                title(ax6, 'Angles de dérives Av/Arr');
                str ={'-  : Avant  ,  -- : Arrière'};
                text(ax6, 0.02, 0.98, str,'Units','normalized','VerticalAlignment', 'top', 'BackgroundColor', 'white', 'EdgeColor', 'black');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.VoiturelgreButton.Value= true;
    app.Params=get_parametres_vehicule("Voiture Legere");
        end

        % Button pushed function: LancerSimulationButton
        function LancerSimulationButtonPushed(app, event)
            Vehicule=app.VhiculesButtonGroup.SelectedObject;
            nom= Vehicule.Text ; 
            
            if contains(nom,"légère")
                nom_v="Voiture Legere" ;
            elseif contains(nom,'Aéro')
                nom_v ="Aéroglisseur" ;
            else
                nom_v ="SUV";
            end
            
            app.Params =get_parametres_vehicule(nom_v);


            type_contact=app.contact.Value; 

            scenario=1;
            if app.rb_Evitement.Value
                scenario=2;
            end
            
            sols = {} ;
            if app.cb_Sec.Value, sols{end+1} = "Sec";end
            if app.cb_Mouille.Value, sols{end+1}= "Mouillé";end
            if app.cb_Neige.Value, sols{end+1}= "Neige";end
            if app.cb_Verglas.Value, sols{end+1} ="Verglas"; end
            
            if isempty(sols)
                uialert(app.UIFigure,"Choix sol obligatoire","Erreur") ;
                return;
            end
            app.runSimulationLogic(nom_v, app.KnobVitesse.Value,app.Angle.Value,app.Duree.Value,sols,scenario,type_contact);
        end

        % Button pushed function: FermerButton
        function FermerButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ParamtresPanel = uipanel(app.LeftPanel);
            app.ParamtresPanel.Title = 'Paramètres';
            app.VitessekmhLabel = uilabel(app.ParamtresPanel);
            app.VitessekmhLabel.Text = 'Vitesse (km/h)';
            app.KnobVitesse = uiknob(app.ParamtresPanel, 'continuous');
            app.KnobVitesse.Limits = [0 130];
            app.KnobVitesse.MajorTicks = [0 20 40 60 80 100 120 130];
            app.KnobVitesse.Value = 40;
            app.ScenarioGroup = uibuttongroup(app.ParamtresPanel);
            app.ScenarioGroup.Title = 'Scénarios';
            app.rb_Virage = uiradiobutton(app.ScenarioGroup);
            app.rb_Virage.Text = 'Virage';
            app.rb_Virage.Value = true;
            app.rb_Evitement = uiradiobutton(app.ScenarioGroup);
            app.rb_Evitement.Text = {'Evitement + Freinage'; 'd''urgence'};
            app.AngleVolantdegLabel = uilabel(app.ParamtresPanel);
            app.AngleVolantdegLabel.Text = 'Angle Volant (deg)';
            app.Angle = uieditfield(app.ParamtresPanel, 'numeric');
            app.Angle.Limits = [-360 360];
            app.Angle.Value = 80;
            app.Duree = uieditfield(app.ParamtresPanel, 'numeric');
            app.Duree.Limits = [0 60];
            app.Duree.Value = 10;
            app.SolsPanel = uipanel(app.ParamtresPanel);
            app.SolsPanel.Title = 'Sols';
            app.cb_Sec = uicheckbox(app.SolsPanel);
            app.cb_Sec.Text = 'Sec';
            app.cb_Mouille = uicheckbox(app.SolsPanel);
            app.cb_Mouille.Text = 'Mouillé';
            app.cb_Neige = uicheckbox(app.SolsPanel);
            app.cb_Neige.Text = 'Neige';
            app.cb_Verglas = uicheckbox(app.SolsPanel);
            app.cb_Verglas.Text = 'Verglas';
            app.LancerSimulationButton = uibutton(app.ParamtresPanel, 'push');
            app.LancerSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerSimulationButtonPushed, true);
            app.LancerSimulationButton.Text = 'Lancer Simulation';
            app.FermerButton = uibutton(app.ParamtresPanel, 'push');
            app.FermerButton.ButtonPushedFcn = createCallbackFcn(app, @FermerButtonPushed, true);
            app.FermerButton.Text = 'Fermer';
            app.AngleVolantdegLabel_2 = uilabel(app.ParamtresPanel);
            app.AngleVolantdegLabel_2.Text = 'Durée de simulation (s)';
            app.TypedecontactdupneumatiquePanel = uipanel(app.ParamtresPanel);
            app.TypedecontactdupneumatiquePanel.Title = 'Type de contact du pneumatique ';
            app.contact = uiswitch(app.TypedecontactdupneumatiquePanel, 'toggle');
            app.contact.Items = {'Linéaire', 'Linéaire avec saturation'};
            app.contact.Value = 'Linéaire';
            app.VhiculesButtonGroup = uibuttongroup(app.LeftPanel);
            app.VhiculesButtonGroup.Title = 'Véhicules';
            app.VoiturelgreButton = uitogglebutton(app.VhiculesButtonGroup);
            app.VoiturelgreButton.Icon = fullfile(pathToMLAPP, 'voiture.jpg');
            app.VoiturelgreButton.Text = 'Voiture légère';
            app.VoiturelgreButton.Value = true;
            app.SUVButton = uitogglebutton(app.VhiculesButtonGroup);
            app.SUVButton.Icon = fullfile(pathToMLAPP, 'pexels-mikebirdy-116675.jpg');
            app.SUVButton.Text = 'SUV';
            app.AroglisseurButton = uitogglebutton(app.VhiculesButtonGroup);
            app.AroglisseurButton.Icon = fullfile(pathToMLAPP, 'aero.jpg');
            app.AroglisseurButton.Text = 'Aéroglisseur';
            app.ResultsPanel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end